classdef kneeAppthree < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        startbtButton   matlab.ui.control.Button
        STARTButton     matlab.ui.control.Button
        STOPButton      matlab.ui.control.Button
        SaveDataButton  matlab.ui.control.Button
        clearbButton    matlab.ui.control.Button
        RightPanel      matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        b % ble
        accel % accel characteristic
        angle % angle characteristic
        width % how long to be plotting
        stopIter % stop plotting
        name
        accelUUID
        angleUUID
        accel_data
        isPlotting
        Fs
        accelDataCellArray
        timeCellArray
        
    end
    
    methods (Access = private)
        
%         function displayCharacteristicData(app,src,evt)
% %             global voltage_data;% timestamps;
% %             [accel_data{end +1} timestamps{end+1}]=read(src,'oldest');
%             [data,timestamp] = read(src,'oldest');
%             app.accel_data{1,end+1} = data;
%             %accel_data{2,end} = timestamp;
%         end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startbtButton
        function startbtButtonPushed(app, event)
            %add code to calibrate stuff here
            
            %Start bluetooth
            app.b = ble("ECG_Device");
            disp("Connected")
            app.name = "386a83e2-28fa-11eb-adc1-0242ac120002";
            app.accelUUID = "5212ddd0-29e5-11eb-adc1-0242ac120002";
            app.angleUUID = "b4250402-fb4b-4746-b2b0-93f0e61122c6";
            app.accel = characteristic(app.b,app.name,app.accelUUID);
            app.angle = characteristic(app.b,app.name,app.angleUUID);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.stopIter = false;
            app.isPlotting = false;
            
            tic
            subscribe(app.accel)
            app.accel.DataAvailableFcn = @displayCharacteristicData;
            subscribe(app.angle)
%             disp("Subscribed")
            
            %begin plotting, take in data
            %x = 1:100;
            %y = 1:100;
%             app.width = 1000;
%             accelData = NaN(app.width,1);
%             angleData = NaN(app.width,1);
%             fontSize = 16;
%             colorTheme1 = [2 121 255]./255;
%             lineWidth = 1;
%             disp("arrays made")
            
            xPlot = NaN;
            accelData = NaN;
            angleData = NaN;
            
            p1 = plot(app.UIAxes,xPlot,accelData);
            
            p2 = plot(app.UIAxes_2,xPlot,angleData);
           
%             disp("plots made")

            i = 1;
            
            while (app.stopIter == false)
                if app.isPlotting == false
                    disp("Plotting")
                    app.isPlotting = true;
                end
%                 tempAccel = read(app.accel);
%                 tempAngle = read(app.angle);
                
%                 accelData(i) = tempAccel(1)+255*tempAccel(2);
%                 angleData(i) = (tempAngle(1)+255*tempAngle(2));
                [app.accelDataCellArray,app.timeCellArray] = getData;
                accelData = cell2mat(app.accelDataCellArray);
%                 accelData = cell2mat(getData)
                
%                 xAccelData(i) = datestr(now,'dd-mm-yyyy HH:MM:SS FFF');
%                 xAccelData{i} = datetime('now','Format','yyyy-MM-dd HH:mm:ss.SSS');
%                 xAngleData{i} = xAccelData{i};
%                 xPlot(i) = toc;
                xPlot = 1:length(accelData);
                
                set(p1,'XData',xPlot,'YData',accelData);
%                 set(p2,'XData',xPlot,'YData',angleData);
                drawnow
                i = i + 1;
            
            end
            %plot(app.UIAxes,x,y);
            app.Fs = length(accelData)/toc;
            disp(app.Fs)
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stopIter = true;
            app.isPlotting = false;
            
            unsubscribe(app.accel);
            app.accel.DataAvailableFcn = [];
            unsubscribe(app.angle);
            
%             clearAccelData;
            clear global
            disp("stopped")
        end

        % Button pushed function: clearbButton
        function clearbButtonPushed(app, event)
            clear app.b
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            exportData = [app.accelDataCellArray app.timeCellArray];
            filename = datestr(datetime);
            filename = strrep(filename,':','_');
            save(filename,'exportData');
            
            disp("data saved")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.startbtButton = uibutton(app.LeftPanel, 'push');
            app.startbtButton.ButtonPushedFcn = createCallbackFcn(app, @startbtButtonPushed, true);
            app.startbtButton.Text = 'start bt';
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SaveDataButton = uibutton(app.LeftPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.clearbButton = uibutton(app.LeftPanel, 'push');
            app.clearbButton.ButtonPushedFcn = createCallbackFcn(app, @clearbButtonPushed, true);
            app.clearbButton.Text = 'clear b';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'GRF')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'GRF')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Knee Angle')
            xlabel(app.UIAxes_2, 'time')
            ylabel(app.UIAxes_2, 'knee angle')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kneeAppthree

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end